classdef Version2App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        VisualizeModelButton     matlab.ui.control.Button
        AutoSaveButton           matlab.ui.control.StateButton
        sleepLamp                matlab.ui.control.Lamp
        EvaluateLamp             matlab.ui.control.Lamp
        stimulusLamp             matlab.ui.control.Lamp
        trainLamp                matlab.ui.control.Lamp
        loadLamp                 matlab.ui.control.Lamp
        SleepButton              matlab.ui.control.Button
        EvaluateButton           matlab.ui.control.Button
        NewStimulusButton        matlab.ui.control.Button
        TrainNetworkButton       matlab.ui.control.Button
        LoadNetworkButton        matlab.ui.control.Button
        KnownLabelsListBox       matlab.ui.control.ListBox
        KnownLabelsListBoxLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        net % network being used
        xTrain %Training Data
        yTrain %Training Labels
        
        knownCategories %Known Categories
        
        autoSave %Save Automatically 
    end
    
    methods (Access = private)
        
        function saveData(app)
            variableFile = "V2Scripts\savedValues\variables.mat";
            
            variables = [{app.knownCategories}; {app.net}; {app.xTrain}; {app.yTrain}];
            
            save(variableFile, "variables")
            
        end
        
        function updateLamps(app, buttonPushed)
            
            switch(buttonPushed)
                case("load")
                    app.loadLamp.Color = [0, 1, 0];
                    app.trainLamp.Color = [0, 1, 0];
                    app.stimulusLamp.Color = [1, 0, 0];
                    app.EvaluateLamp.Color = [1,0,0];
                    app.sleepLamp.Color = [1,0,0];
                case("train")
                    app.loadLamp.Color = [1, 0, 0];
                    app.trainLamp.Color = [0, 1, 0];
                    app.stimulusLamp.Color = [1, 0, 0];
                    app.EvaluateLamp.Color = [1,0,0];
                    app.sleepLamp.Color = [1,0,0];
                case("newStimulus")
                    app.loadLamp.Color = [1, 0, 0];
                    app.trainLamp.Color = [1, 0, 0];
                    app.stimulusLamp.Color = [0, 1, 0];
                    app.EvaluateLamp.Color = [1,0,0];
                    app.sleepLamp.Color = [1,0,0];
                case("evaluate")
                    app.EvaluateLamp.Color = [0, 1,0];
                case("sleep")
                    app.loadLamp.Color = [1, 0, 0];
                    app.trainLamp.Color = [1, 0, 0];
                    app.stimulusLamp.Color = [1, 0, 0];
                    app.EvaluateLamp.Color = [1,0,0];
                    app.sleepLamp.Color = [0,1,0];
            end
    
        end
        
        function updateKnownLabelsList(app)
            tempArray = [];
            for index = 1: size(app.knownCategories, 2)
                currentCategory = string(app.knownCategories(index));
                
                numberOfObservations = size(find(app.yTrain == currentCategory), 1);
                
                tempArray = [tempArray; {currentCategory + " " + num2str(numberOfObservations)}];
            end
            
            app.KnownLabelsListBox.Items = string(tempArray);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, knownCategories)

            variableFile = "V2Scripts\savedValues\variables.mat";
            
            if(isfile(variableFile) == 1)
                load(variableFile)
                
                app.knownCategories = variables{1};
                app.net = variables{2};
                app.xTrain = variables{3};
                app.yTrain = variables{4};
                
                updateLamps(app, "load")
                updateKnownLabelsList(app)
                
            else
                app.knownCategories = ["zero", "one", "two", "unknown"];
                [app.xTrain, app.yTrain] = generateTrainingData(app.knownCategories);
            end
            
            app.autoSave = 1;


        end

        % Button pushed function: TrainNetworkButton
        function TrainNetworkButtonPushed(app, event)
            
            size(app.xTrain)
            size(app.yTrain)
            
            app.net = trainModel(app.xTrain, app.yTrain, size(app.knownCategories, 2));
            
            if(app.autoSave == 1)
                saveData(app);
            end
            
            updateLamps(app, "train");
            
        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
            evaluateModel(app.net)
            
            updateLamps(app, "evaluate");
        end

        % Value changed function: AutoSaveButton
        function AutoSaveButtonValueChanged(app, event)
            app.autoSave = app.AutoSaveButton.Value;
        end

        % Button pushed function: LoadNetworkButton
        function LoadNetworkButtonPushed(app, event)


            updateLamps(app, "load");

        end

        % Button pushed function: NewStimulusButton
        function NewStimulusButtonPushed(app, event)
            
            [app.xTrain, app.yTrain, app.knownCategories] = newStimulus(app.knownCategories, app.xTrain, app.yTrain);
            
            updateKnownLabelsList(app)
            
            updateLamps(app, "newStimulus");
        end

        % Button pushed function: SleepButton
        function SleepButtonPushed(app, event)
            
            [app.xTrain, app.yTrain] = sleepModel(app.net, app.xTrain, app.yTrain, "unknown");
            
            updateKnownLabelsList(app)
            
            updateLamps(app, "sleep");
            
        end

        % Button pushed function: VisualizeModelButton
        function VisualizeModelButtonPushed(app, event)
            
            
            for layerNumber = 2:4:10
                visualizeLayers(app.net, layerNumber, [1:8]);
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.KnownLabelsListBoxLabel = uilabel(app.UIFigure);
            app.KnownLabelsListBoxLabel.Text = 'Known Labels';
            app.KnownLabelsListBox = uilistbox(app.UIFigure);
            app.LoadNetworkButton = uibutton(app.UIFigure, 'push');
            app.LoadNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNetworkButtonPushed, true);
            app.LoadNetworkButton.Text = 'Load Network';
            app.TrainNetworkButton = uibutton(app.UIFigure, 'push');
            app.TrainNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @TrainNetworkButtonPushed, true);
            app.TrainNetworkButton.Text = 'Train Network';
            app.NewStimulusButton = uibutton(app.UIFigure, 'push');
            app.NewStimulusButton.ButtonPushedFcn = createCallbackFcn(app, @NewStimulusButtonPushed, true);
            app.NewStimulusButton.Text = 'New Stimulus';
            app.EvaluateButton = uibutton(app.UIFigure, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Text = 'Evaluate';
            app.SleepButton = uibutton(app.UIFigure, 'push');
            app.SleepButton.ButtonPushedFcn = createCallbackFcn(app, @SleepButtonPushed, true);
            app.SleepButton.Text = 'Sleep';
            app.loadLamp = uilamp(app.UIFigure);
            app.trainLamp = uilamp(app.UIFigure);
            app.stimulusLamp = uilamp(app.UIFigure);
            app.EvaluateLamp = uilamp(app.UIFigure);
            app.sleepLamp = uilamp(app.UIFigure);
            app.AutoSaveButton = uibutton(app.UIFigure, 'state');
            app.AutoSaveButton.ValueChangedFcn = createCallbackFcn(app, @AutoSaveButtonValueChanged, true);
            app.AutoSaveButton.Text = 'Auto Save';
            app.AutoSaveButton.Value = true;
            app.VisualizeModelButton = uibutton(app.UIFigure, 'push');
            app.VisualizeModelButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeModelButtonPushed, true);
            app.VisualizeModelButton.Text = 'Visualize Model';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Version2App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end