classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        EvaluateAllButton    matlab.ui.control.Button
        newStimulusLamp      matlab.ui.control.Lamp
        NewStimulusButton    matlab.ui.control.Button
        saveNetworkLamp      matlab.ui.control.Lamp
        SaveNetworkButton    matlab.ui.control.Button
        KnownLabels          matlab.ui.control.Label
        ListBox              matlab.ui.control.ListBox
        NewLabelEditField    matlab.ui.control.EditField
        TrainNetworkButton   matlab.ui.control.Button
        LoadOldNetButton     matlab.ui.control.Button
        loadNetLamp          matlab.ui.control.Lamp
        trainingNetworkLamp  matlab.ui.control.Lamp
        ShowResultsButton    matlab.ui.control.Button
        showResultsLamp      matlab.ui.control.Lamp
        SleepLamp            matlab.ui.control.Lamp
        SleepButton          matlab.ui.control.StateButton
        UIAxes               matlab.ui.control.UIAxes
    end


    properties (Access = public)
        newLabel % Description
        net
        xTrain
        yTrain
        EnableOtherButtons
        loadedNet
        knownLabels
        preTrained
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, image)
            app.loadedNet = 0;
            app.EnableOtherButtons = 1;
            app.knownLabels = ["Zero","One","Two", "Unknown"];
            app.preTrained = 0;
            
            
            app.loadNetLamp.Color = "#bc153b";
            app.trainingNetworkLamp.Color = "#bc153b";
            app.showResultsLamp.Color = "#bc153b";
            app.saveNetworkLamp.Color = "#bc153b";
            app.newStimulusLamp.Color = "#bc153b";
            app.SleepLamp.Color = "#bc153b";
            
            [app.xTrain, app.yTrain] = getTrainingData;

            xlabel(app.UIAxes, "")
            ylabel(app.UIAxes, "")

            app.ListBox.Items = app.knownLabels;

        end

        % Callback function
        function AmplitudeSliderValueChanged(app, event)
            value = app.AmplitudeSlider.Value;
            plot(app.UIAxes, value * peaks)
        end

        % Value changed function: NewLabelEditField
        function NewLabelEditFieldValueChanged(app, event)
            app.newLabel = app.NewLabelEditField.Value;

            app.knownLabels = [app.knownLabels(1:end-1), string(app.newLabel), app.knownLabels(end)];

            app.ListBox.Items = app.knownLabels;

        end

        % Callback function
        function SubmitAnswerButtonPushed(app, event)
            disp(app.newLabel)
            delete(app);
        end

        % Button pushed function: TrainNetworkButton
        function TrainNetworkButtonPushed(app, event)
            if(app.EnableOtherButtons == 1)

                app.trainingNetworkLamp.Color = "#bc153b";
                if(app.preTrained == 1)
                    returnStuff = trainCNNNetwork(app.xTrain, app.yTrain, "preTrained", app.net(1), "showGraph", 0, "numKnownLabels", ...
                       size(app.knownLabels, 2) - 1);
                else
                    returnStuff = trainCNNNetwork(app.xTrain, app.yTrain, "showGraph", 0, "numKnownLabels", ...
                       size(app.knownLabels, 2) - 1);
                end
                
                app.net = returnStuff{1};

                app.trainingNetworkLamp.Color = "#029534";
                app.saveNetworkLamp.Color = "#bc153b";
                app.loadNetLamp.Color = "#bc153b";
                
                labelsFileName = "trainedNets/labels.mat";
                trainingLabels = app.knownLabels;
                save(labelsFileName, 'trainingLabels')
                
            end
        end

        % Button pushed function: LoadOldNetButton
        function LoadOldNetButtonPushed(app, event)
            if(app.EnableOtherButtons == 1)
                load("trainedNets/network" + (size(app.knownLabels, 2) - 1) + ".mat");
                load("trainedNets/labels.mat");
                load("trainedNets/xTrain.mat");
                load("trainedNets/yTrain.mat");
                
                app.net = net;
                app.knownLabels = trainingLabels;
                app.xTrain = xTrain;
                app.yTrain = yTrain;
                
                
                app.ListBox.Items = app.knownLabels;

                app.loadNetLamp.Color = "#029534";
                app.loadedNet(1) = 1;
            end
        end

        % Button pushed function: ShowResultsButton
        function ShowResultsButtonPushed(app, event)
            if(app.EnableOtherButtons == 1)
                fs = 20;

                index = round(rand(1,1) * size(app.xTrain, 4), 0) + 1;
                randomImage = app.xTrain(:,:,:, index);

                imshow(randomImage, 'Parent', app.UIAxes);

                guess = classify(app.net(1), randomImage);
                real = app.yTrain(index);

                title(app.UIAxes, string(real) + " | " + string(guess), "fontSize", fs);

            end


        end

        % Callback function
        function SleepButtonPushed(app, event)


        end

        % Value changed function: SleepButton
        function SleepButtonValueChanged(app, event)
            app.EnableOtherButtons = ~(app.SleepButton.Value);

            if(app.EnableOtherButtons == 0)
                set(app.LoadOldNetButton, 'Backgroundcolor', '#afaccc')
                set(app.TrainNetworkButton, 'Backgroundcolor', '#afaccc')
                set(app.ShowResultsButton, 'Backgroundcolor', '#afaccc')
                set(app.SaveNetworkButton, 'Backgroundcolor', '#afaccc')
                
                [app.xTrain, app.yTrain, ~] = sleepyTime(app.net, app.xTrain, app.yTrain);
                
                app.loadNetLamp.Color = "#029534";
                
            else
                set(app.LoadOldNetButton, 'Backgroundcolor', '#dfddea')
                set(app.TrainNetworkButton, 'Backgroundcolor', '#dfddea')
                set(app.ShowResultsButton, 'Backgroundcolor', '#dfddea')
                set(app.SaveNetworkButton, 'Backgroundcolor', '#dfddea')
                app.SleepLamp.Color = "#bc153b";
            end


        end

        % Button pushed function: SaveNetworkButton
        function SaveNetworkButtonPushed(app, event)
            netFileName = "trainedNets/network.mat";
            labelsFileName = "trainedNets/labels.mat";
            xTrainFileName = "trainedNets/xTrain.mat";
            yTrainFileName = "trainedNets/yTrain.mat";
            
            
            net = app.net;
            trainingLabels = app.knownLabels;
            xTrain = app.xTrain;
            yTrain = app.yTrain;
            
            save(netFileName, 'net')
            save(labelsFileName,'trainingLabels')
            save(xTrainFileName,'xTrain')
            save(yTrainFileName,'yTrain')
            
            app.saveNetworkLamp.Color = "#029534";

        end

        % Button pushed function: NewStimulusButton
        function NewStimulusButtonPushed(app, event)

            app.newStimulusLamp.Color = "#bc153b";
            for i = 1: 100
                fprintf("\n%d", i)
                [imageData, guess, real] = getNewStimulus(app.net, app.knownLabels);

                %             imshow(imageData, 'Parent', app.UIAxes);

                app.knownLabels = unique([app.knownLabels(1:end-1), string(real), app.knownLabels(end)]);

                [app.xTrain, app.yTrain] = addToDataset(imageData, app.xTrain, app.yTrain, real);

                app.preTrained = 0;

                app.ListBox.Items = app.knownLabels;


            end
            app.newStimulusLamp.Color = "#029534";
            
            
        end

        % Button pushed function: EvaluateAllButton
        function EvaluateAllButtonPushed(app, event)
            evaluate(app.net)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SleepButton = uibutton(app.UIFigure, 'state');
            app.SleepButton.ValueChangedFcn = createCallbackFcn(app, @SleepButtonValueChanged, true);
            app.SleepButton.Text = 'Sleep';
            app.SleepLamp = uilamp(app.UIFigure);
            app.showResultsLamp = uilamp(app.UIFigure);
            app.ShowResultsButton = uibutton(app.UIFigure, 'push');
            app.ShowResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowResultsButtonPushed, true);
            app.ShowResultsButton.Text = 'Show Results';
            app.trainingNetworkLamp = uilamp(app.UIFigure);
            app.loadNetLamp = uilamp(app.UIFigure);
            app.LoadOldNetButton = uibutton(app.UIFigure, 'push');
            app.LoadOldNetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOldNetButtonPushed, true);
            app.LoadOldNetButton.Text = 'Load Old Net';
            app.TrainNetworkButton = uibutton(app.UIFigure, 'push');
            app.TrainNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @TrainNetworkButtonPushed, true);
            app.TrainNetworkButton.Text = 'Train Network';
            app.NewLabelEditField = uieditfield(app.UIFigure, 'text');
            app.NewLabelEditField.ValueChangedFcn = createCallbackFcn(app, @NewLabelEditFieldValueChanged, true);
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Item 1'};
            app.KnownLabels = uilabel(app.UIFigure);
            app.KnownLabels.Text = 'Known Labels';
            app.SaveNetworkButton = uibutton(app.UIFigure, 'push');
            app.SaveNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNetworkButtonPushed, true);
            app.SaveNetworkButton.Text = 'Save Network';
            app.saveNetworkLamp = uilamp(app.UIFigure);
            app.NewStimulusButton = uibutton(app.UIFigure, 'push');
            app.NewStimulusButton.ButtonPushedFcn = createCallbackFcn(app, @NewStimulusButtonPushed, true);
            app.NewStimulusButton.Text = 'New Stimulus';
            app.newStimulusLamp = uilamp(app.UIFigure);
            app.EvaluateAllButton = uibutton(app.UIFigure, 'push');
            app.EvaluateAllButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateAllButtonPushed, true);
            app.EvaluateAllButton.Text = 'Evaluate All';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end