classdef boxingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        SubmitButton         matlab.ui.control.Button
        NextImageButton      matlab.ui.control.Button
        LabelsListBox        matlab.ui.control.ListBox
        LabelsListBoxLabel   matlab.ui.control.Label
        Point2Label          matlab.ui.control.Label
        Y2                   matlab.ui.control.Spinner
        X2                   matlab.ui.control.Spinner
        Point1Label          matlab.ui.control.Label
        Y1                   matlab.ui.control.Spinner
        X1                   matlab.ui.control.Spinner
        DrawSquareButton     matlab.ui.control.Button
        LabelEditField       matlab.ui.control.EditField
        LabelEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        labels % Description
        currentImage %The current image being displyed on the graph
        dataset %Dataset of image names, point 1 and point 2
        imageFile
        root
        count 
    end
    
    methods (Access = private)
        
        function updateList(app)
            app.LabelsListBox.Items = app.labels;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, a)
            app.root = "appForMakingObjectDetectionDataset/";
            
            cookiesFile = app.root + "cookies.mat";
            if(isfile(cookiesFile))
                a = load(cookiesFile);
                app.count = a.count;
            else
                app.count = 1;
            end

            labelsFile = app.root + "labels.mat";
            if(isfile(labelsFile))
                a = load(labelsFile);
                app.labels = a.labels;
                updateList(app);
            else
                app.labels = ["cow"]
            end
            
            app.currentImage = ones(100, 100, 3);
            
            figure
            imagesc(app.currentImage)
            
            datasetFile = app.root + "dataset.mat";
            if(isfile(datasetFile))
                a = load(datasetFile);
                app.dataset = a.dataset;
            end

            app.imageFile = app.root + "1.jpg";


        end

        % Button pushed function: DrawSquareButton
        function DrawSquareButtonPushed(app, event)
            [x, y] = ginput(2);

            point1 = [int16(x(1)),int16(y(1))];
            point2 = [int16(x(2)),int16(y(2))];
            thickness = 1;
            
            app.X1.Value = double(point1(1));
            app.Y1.Value = double(point1(2));

            app.X2.Value = double(point2(1));
            app.Y2.Value = double(point2(2));

            [imageWithSquare] = drawSquare(app.currentImage, point1, point2,...
                "showImage", 0, "thickness", thickness);
            
            imagesc(imageWithSquare)


        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            value = lower(string(app.LabelEditField.Value));
            
            if(~ismember(app.labels, value))
                app.labels = [app.labels; value];
            end

            updateList(app);

            point1 = [app.X1.Value, app.Y1.Value];
            point2 = [app.X2.Value, app.Y2.Value];

            app.dataset = [app.dataset; {app.imageFile, point1, point2, value}];
            
            labels = app.labels;
            dataset = app.dataset;

            save(app.root + "labels.mat", 'labels')
            save(app.root + "dataset.mat", 'dataset')


        end

        % Button pushed function: NextImageButton
        function NextImageButtonPushed(app, event)
            try
            app.imageFile = app.root + "/images/" + string(app.count) + ".jpg";
            app.currentImage = imread(app.imageFile);
            catch
                app.count = 1;
                app.imageFile = app.root + "images/" + string(app.count) + ".jpg";
                app.currentImage = imread(app.imageFile);
            end

            app.currentImage = imresize(app.currentImage, [1024, 1024]);

            imagesc(app.currentImage)

            app.count = app.count + 1;
            count = app.count;

            save(app.root + "cookies.mat", 'count');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LabelEditFieldLabel = uilabel(app.UIFigure);
            app.LabelEditField = uieditfield(app.UIFigure, 'text');
            app.DrawSquareButton = uibutton(app.UIFigure, 'push');
            app.DrawSquareButton.ButtonPushedFcn = createCallbackFcn(app, @DrawSquareButtonPushed, true);
            app.DrawSquareButton.Text = 'Draw Square';
            app.X1 = uispinner(app.UIFigure);
            app.Y1 = uispinner(app.UIFigure);
            app.Point1Label = uilabel(app.UIFigure);
            app.Point1Label.Text = 'Point 1:';
            app.X2 = uispinner(app.UIFigure);
            app.Y2 = uispinner(app.UIFigure);
            app.Point2Label = uilabel(app.UIFigure);
            app.Point2Label.Text = 'Point 2:';
            app.LabelsListBoxLabel = uilabel(app.UIFigure);
            app.LabelsListBoxLabel.Text = 'Labels';
            app.LabelsListBox = uilistbox(app.UIFigure);
            app.LabelsListBox.Items = {};
            app.LabelsListBox.Value = {};
            app.NextImageButton = uibutton(app.UIFigure, 'push');
            app.NextImageButton.ButtonPushedFcn = createCallbackFcn(app, @NextImageButtonPushed, true);
            app.NextImageButton.Text = 'Next Image';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = boxingApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end